classdef XBTPlotterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MAINTab                         matlab.ui.container.Tab
        ExporttoASCIIFileButton         matlab.ui.control.Button
        FTPPanel_2                      matlab.ui.container.Panel
        CallsignListBox                 matlab.ui.control.ListBox
        CallsignListBoxLabel            matlab.ui.control.Label
        EndDatePicker                   matlab.ui.control.DatePicker
        EndDatePickerLabel              matlab.ui.control.Label
        StartDatePicker                 matlab.ui.control.DatePicker
        StartDatePickerLabel            matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        LocalPanel                      matlab.ui.container.Panel
        BinariesListBox                 matlab.ui.control.ListBox
        SelectFilesButton               matlab.ui.control.Button
        PlotSelectionButton             matlab.ui.control.Button
        FontSlider                      matlab.ui.control.Slider
        FontSliderLabel                 matlab.ui.control.Label
        MetadataTextArea                matlab.ui.control.TextArea
        MetadataTextAreaLabel           matlab.ui.control.Label
        MapPanel                        matlab.ui.container.Panel
        ProfilePanel                    matlab.ui.container.Panel
        CONFIGTab                       matlab.ui.container.Tab
        XBTPLOTTERv1012023Label         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DefaultconfigurationButton      matlab.ui.control.StateButton
        CurrentfilepathEditField        matlab.ui.control.EditField
        CurrentfilepathEditFieldLabel   matlab.ui.control.Label
        MapTypeDropDown                 matlab.ui.control.DropDown
        MapTypeDropDownLabel            matlab.ui.control.Label
        ScaleFactorEditField            matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel       matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        SaveconfigurationButton         matlab.ui.control.Button
        FTPPanel                        matlab.ui.container.Panel
        EditCheckBox                    matlab.ui.control.CheckBox
        RemoteDirectoryEditField        matlab.ui.control.EditField
        RemoteDirectoryEditFieldLabel   matlab.ui.control.Label
        PassEditField                   matlab.ui.control.EditField
        PassEditFieldLabel              matlab.ui.control.Label
        UserEditField                   matlab.ui.control.EditField
        UserEditFieldLabel              matlab.ui.control.Label
        HostEditField                   matlab.ui.control.EditField
        HostEditFieldLabel              matlab.ui.control.Label
        WorkingDirectoryEditField       matlab.ui.control.EditField
        WorkingDirectoryEditFieldLabel  matlab.ui.control.Label
        TABLETab                        matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        binariesByDate % list of binary files filtered by date as dir;file
        binariesByCallsign % list of binary files filtered by date and callsign as dir;file
        basicMetaTable % table with basic binaries metadata used to plot
        localDirSelected % local directory with selected files (local Panel)
        server % server object values from GUI CONFIG tab
    end
    
    methods (Access = public)
        
        function lineCallback(app,src,~, basicMetadata)
            a = get(src);
            if src.Color == [1 0 0] % if red make it black
                src.Color = 'black';
            else
                src.Color = 'red'; % if black make it red again
            end        
        %     disp(a.Color);
            clickedID = string(a.LatitudeData) + ";" + string(a.LongitudeData);
            disp(clickedID);
            file = basicMetadata(ismember(basicMetadata.ID, clickedID),'FileName');
            disp(file);
        end


        function get_pressed_key(app,src,event)
            app.MetadataTextArea.Value = event.Key;
        end
        
        function pan_map(app,gx,key)
            [lat,lon]=geolimits(gx); % get current limits
            dLat = app.ScaleFactorEditField.Value; % take from config
            dLon = app.ScaleFactorEditField.Value; % take from config
            lonMin = lon(1);
            lonMax = lon(2);
            latMin = lat(1);
            latMax = lat(2);
            switch key
                case "rightarrow"
                    lonMin = lon(1) + dLon;
                    lonMax = lon(2) + dLon;
                case "leftarrow"
                    lonMin = lon(1) - dLon;
                    lonMax = lon(2) - dLon;  
                case "uparrow"
                    latMin = lat(1) + dLat;
                    latMax = lat(2) + dLat;  
                case "downarrow"
                    latMin = lat(1) - dLat;
                    latMax = lat(2) - dLat;  
                case "add"
                    latMin = lat(1) + dLat;
                    latMax = lat(2) - dLat;  
                    lonMin = lon(1) + dLon;
                    lonMax = lon(2) - dLon;
                case "subtract"
                    latMin = lat(1) - dLat;
                    latMax = lat(2) + dLat;  
                    lonMin = lon(1) - dLon;
                    lonMax = lon(2) + dLon;                  
            end

            geolimits(gx,[latMin latMax],[lonMin lonMax]);
        end
        
        function cleanTable = clean_table(app,basicTable)                       
            index = basicTable.Index(:);
            callsign = basicTable.CallSign(:);
            ship = basicTable.Ship(:);
            line = basicTable.Line(:);
            date_time = basicTable.DateTime(:);
            lat = basicTable.Latitude(:);
            lon = basicTable.Longitude(:);
            file = basicTable.FileName(:);

            cleanTable = table(index,callsign,ship,line,date_time,lat,lon,file);
        end
        
        function load_configuration(app)
            configFilePath = "XBTPlotter_configuration"; % file order: host,user,pass,remoteDir,mapType,ScaleFactor,WorkingDir
            try
                if exist(configFilePath,"file")
                    fconf = fopen(configFilePath,"r");
                    % Load FTP data
                    app.HostEditField.Value = fgetl(fconf);
                    app.UserEditField.Value = fgetl(fconf);
                    app.PassEditField.Value = fgetl(fconf);
                    app.RemoteDirectoryEditField.Value = fgetl(fconf);
                    % Load MapType
                    app.MapTypeDropDown.Value = fgetl(fconf);
                    % Load ScaleFactor
                    app.ScaleFactorEditField.Value = str2double( fgetl(fconf) );
                    app.WorkingDirectoryEditField.Value = fgetl(fconf);                  

                    fclose(fconf);
                    fclose("all"); % make sure all is closed
                    % update config status button
                    app.DefaultconfigurationButton.Value = true;
                    app.DefaultconfigurationButton.BackgroundColor = "green";
                    app.DefaultconfigurationButton.Text = "Configuration loaded";
                    app.DefaultconfigurationButton.FontColor = 'white';
                    app.DefaultconfigurationButton.FontWeight = 'bold';

                end % end if file exists
            catch e
                uialert(app.UIFigure,e.message,'Error');
            end
        end


    end % Public methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%              app.gx = geoaxes(app.MapPanel);
%              geoplot(app.gx,30.0,-80.0);
%              app.currentAxes = gca;
%              https://www.mathworks.com/help/matlab/creating_guis/graphics-support-in-app-designer.html
            % Make current directory = working directory
            app.WorkingDirectoryEditField.Value = pwd;
            % Set end date as today and start day -7 days
            app.StartDatePicker.Value = datetime('now') - days(7);
            app.EndDatePicker.Value = datetime('now');
            load_configuration(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            app.server.host = app.HostEditField.Value;
            app.server.user = app.UserEditField.Value;
            app.server.pass = app.PassEditField.Value;
            app.server.directory = app.RemoteDirectoryEditField.Value;

            startDate = app.StartDatePicker.Value;
            endDate = app.EndDatePicker.Value;
            a = datetime(startDate.Year,startDate.Month,startDate.Day);
            b = datetime(endDate.Year,endDate.Month,endDate.Day);

            [app.binariesByDate, callSigns] = filter_binaries_by_date(a,b,app.server); % list of binary files as dir;file filtered by date
            app.CallsignListBox.Items = callSigns; % assign callsigns available in date range 
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % remote file plot

            callsignList = app.CallsignListBox.Value; % update available callsigns filtered by date
            app.binariesByCallsign = filter_binaries_by_callsign(app.binariesByDate,callsignList); % returns binary files as dir;file filtered by callsign (and date)
            app.basicMetaTable = get_basic_metadata_remote(app.binariesByCallsign, app.server, app.WorkingDirectoryEditField.Value); % returns table with basic metadata from downloaded binaries
            gx = map_plotter(app.basicMetaTable, app.MapPanel, app.ProfilePanel,app.WorkingDirectoryEditField.Value, app.MetadataTextArea, app.MapTypeDropDown, app.CurrentfilepathEditField); % map plot / clickable points to display graph
            
            % Set pan/zoom function to move around the map with arrow keys
            set(app.UIFigure,'KeyPressFcn',@get_pressed_key);
            function get_pressed_key(src,event)
                k = event.Key;
%                 app.MetadataTextArea.Value = string(k);
                pan_map(app,gx,k);
            end       
            
            app.UITable.Data = clean_table(app,app.basicMetaTable); % Clean table for display


        end

        % Value changed function: FontSlider
        function FontSliderValueChanged(app, event)
            x = app.FontSlider.Value;
            app.MetadataTextArea.FontSize = 14 + 10 * x;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            workingDir = uigetdir();
            app.WorkingDirectoryEditField.Value = workingDir;
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            [files,path] = uigetfile('*.bin','Select One or More Binaries','MultiSelect', 'on');
            app.BinariesListBox.Items = convertCharsToStrings(files) ; % char array with filenames
            app.localDirSelected = path; % char
        end

        % Button pushed function: PlotSelectionButton
        function PlotSelectionButtonPushed(app, event)
            % Local fil plot
            path = app.localDirSelected;
            binaryList = convertCharsToStrings(app.BinariesListBox.Items);
            app.basicMetaTable = get_basic_metadata_local(path,binaryList);
            gx = map_plotter(app.basicMetaTable, app.MapPanel, app.ProfilePanel,app.WorkingDirectoryEditField.Value, app.MetadataTextArea, app.MapTypeDropDown, app.CurrentfilepathEditField); % map plot / clickable points to display graph

            % Set pan/zoom function to move around the map with arrow keys
            set(app.UIFigure,'KeyPressFcn',@get_pressed_key);
            function get_pressed_key(src,event)
                k = event.Key;
%                 app.MetadataTextArea.Value = string(k);
                pan_map(app,gx,k);
            end       
            
            app.UITable.Data = clean_table(app,app.basicMetaTable); % Clean table for display
        end

        % Value changed function: EditCheckBox
        function EditCheckBoxValueChanged(app, event)
            value = app.EditCheckBox.Value;
            if value == 1
                app.HostEditField.Enable = true;
                app.UserEditField.Enable = true;
                app.PassEditField.Enable = true;
                app.RemoteDirectoryEditField.Enable = true;
            else
                app.HostEditField.Enable = false;
                app.UserEditField.Enable = false;
                app.PassEditField.Enable = false;
                app.RemoteDirectoryEditField.Enable = false;
            end

        end

        % Button pushed function: ExporttoASCIIFileButton
        function ExporttoASCIIFileButtonPushed(app, event)
            bar = waitbar(0,"Starting conversion");
            pause(0.2);
            % input binary file to convert
            fclose('all');
            sourceFilePath = app.CurrentfilepathEditField.Value; % absolute source file path (IN)
            [sourceDirPath, sourceFileName, sourceFileExtension] = fileparts(sourceFilePath);
            % output ascii file      
            workingDir = app.WorkingDirectoryEditField.Value;
            outputDir = "XBTascii_export";
            outputDirPath = fullfile(workingDir,outputDir); % absolute output directory path
            if ~exist(outputDirPath,"dir")
                mkdir(outputDirPath);
            end         
            asciiFile = sourceFileName + "_ascii.txt";
            outputFilePath = fullfile(outputDirPath,asciiFile); % absolute output file path (OUT)
            asciiContent = bin2ascii_export(sourceFilePath); % text to be sent to file
            sourceFileName_msg = strrep(asciiFile,"_","\_");
            waitbar(0.5, bar,"Writing ascii content to " + sourceFileName_msg + "_ascii.txt");
            pause(0.2);
            fout = fopen(outputFilePath,'w');
            fprintf(fout,'%s', asciiContent);            
            fclose(fout);
            waitbar(1, bar,"Done");
            pause(0.2);
            close(bar);
            msgbox([asciiFile; "saved to:"; outputDirPath],"Export complete");

        end

        % Button pushed function: SaveconfigurationButton
        function SaveconfigurationButtonPushed(app, event)
            bar = waitbar(0,"Saving your configuration");
            pause(0.5);
            fclose("all");
            
            configFilePath = "XBTPlotter_configuration"; % save it wherever you're working with the app 
            try
                fout = fopen(configFilePath,"w"); % create file to store config
                % save server info
                fprintf(fout,string(app.HostEditField.Value));
                fprintf(fout,newline);
                fprintf(fout,string(app.UserEditField.Value));
                fprintf(fout,newline);
                fprintf(fout,string(app.PassEditField.Value));
                fprintf(fout,newline);
                fprintf(fout,string(app.RemoteDirectoryEditField.Value));
                fprintf(fout,newline);  
                % save map info
                fprintf(fout,string(app.MapTypeDropDown.Value));
                fprintf(fout,newline);                 
                fprintf(fout,string(app.ScaleFactorEditField.Value));
                fprintf(fout,newline); 
                % general                
                wdir = strrep(string(app.WorkingDirectoryEditField.Value),"\","\\");
                fprintf(fout,wdir);
                fprintf(fout,newline);                 
            catch e
                uialert(app.UIFigure,e.message,'Error');
                fclose("all");
            end
            fclose(fout);
            fclose("all");
            if exist(configFilePath,"file")
                waitbar(1,bar,"Configuration stored in App dir");
            end
            pause(0.5);
            close(bar);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'xbt_fig_clear.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MAINTab = uitab(app.TabGroup);
            app.MAINTab.Title = 'MAIN';
            app.ProfilePanel = uipanel(app.MAINTab);
            app.ProfilePanel.Tooltip = {'Click on any deployment site to display profile'};
            app.ProfilePanel.Title = 'Profile';
            app.MapPanel = uipanel(app.MAINTab);
            app.MapPanel.Tooltip = {'PAN with arrow Keys'; 'ZOOM IN/OUT with +/- Keys'; ''; 'Set Scale Factor in CONFIG'};
            app.MapPanel.Title = 'Map';
            app.MetadataTextAreaLabel = uilabel(app.MAINTab);
            app.MetadataTextAreaLabel.Text = 'Metadata';
            app.MetadataTextArea = uitextarea(app.MAINTab);
            app.MetadataTextArea.Editable = 'off';
            app.MetadataTextArea.Tooltip = {'Click on any deployment site to display MetaData'};
            app.FontSliderLabel = uilabel(app.MAINTab);
            app.FontSliderLabel.Text = 'Font';
            app.FontSlider = uislider(app.MAINTab);
            app.FontSlider.Limits = [-1 1];
            app.FontSlider.ValueChangedFcn = createCallbackFcn(app, @FontSliderValueChanged, true);
            app.LocalPanel = uipanel(app.MAINTab);
            app.LocalPanel.Title = 'Local';
            app.PlotSelectionButton = uibutton(app.LocalPanel, 'push');
            app.PlotSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectionButtonPushed, true);
            app.PlotSelectionButton.Text = 'Plot Selection';
            app.SelectFilesButton = uibutton(app.LocalPanel, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.BinariesListBox = uilistbox(app.LocalPanel);
            app.BinariesListBox.Items = {'Selected Files'};
            app.BinariesListBox.Enable = 'off';
            app.BinariesListBox.Value = 'Selected Files';
            app.FTPPanel_2 = uipanel(app.MAINTab);
            app.FTPPanel_2.Title = 'FTP';
            app.ApplyButton = uibutton(app.FTPPanel_2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.PlotButton = uibutton(app.FTPPanel_2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StartDatePickerLabel = uilabel(app.FTPPanel_2);
            app.StartDatePickerLabel.Text = 'Start';
            app.StartDatePicker = uidatepicker(app.FTPPanel_2);
            app.EndDatePickerLabel = uilabel(app.FTPPanel_2);
            app.EndDatePickerLabel.Text = 'End';
            app.EndDatePicker = uidatepicker(app.FTPPanel_2);
            app.CallsignListBoxLabel = uilabel(app.FTPPanel_2);
            app.CallsignListBoxLabel.Text = 'Callsign';
            app.CallsignListBox = uilistbox(app.FTPPanel_2);
            app.CallsignListBox.Items = {'Filter CallSigns'};
            app.CallsignListBox.Multiselect = 'on';
            app.CallsignListBox.Tooltip = {'Select CallSigns to display on Map.'; 'Multiple selections are possible.'; 'Filter by date first.'};
            app.CallsignListBox.Value = {'Filter CallSigns'};
            app.ExporttoASCIIFileButton = uibutton(app.MAINTab, 'push');
            app.ExporttoASCIIFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoASCIIFileButtonPushed, true);
            app.ExporttoASCIIFileButton.Text = 'Export to ASCII File';
            app.CONFIGTab = uitab(app.TabGroup);
            app.CONFIGTab.Title = 'CONFIG';
            app.WorkingDirectoryEditFieldLabel = uilabel(app.CONFIGTab);
            app.WorkingDirectoryEditFieldLabel.Text = 'Working Directory';
            app.WorkingDirectoryEditField = uieditfield(app.CONFIGTab, 'text');
            app.WorkingDirectoryEditField.Tooltip = {'Set working directory where the binary files will be downloaded in THISDIRECTORY/XBTplotter_downloads'};
            app.FTPPanel = uipanel(app.CONFIGTab);
            app.FTPPanel.Title = 'FTP';
            app.HostEditFieldLabel = uilabel(app.FTPPanel);
            app.HostEditFieldLabel.Enable = 'off';
            app.HostEditFieldLabel.Text = 'Host';
            app.HostEditField = uieditfield(app.FTPPanel, 'text');
            app.HostEditField.Enable = 'off';
            app.HostEditField.Value = 'ftp.aoml.noaa.gov';
            app.UserEditFieldLabel = uilabel(app.FTPPanel);
            app.UserEditFieldLabel.Enable = 'off';
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.FTPPanel, 'text');
            app.UserEditField.Enable = 'off';
            app.UserEditField.Value = 'anonymous';
            app.PassEditFieldLabel = uilabel(app.FTPPanel);
            app.PassEditFieldLabel.Enable = 'off';
            app.PassEditFieldLabel.Text = 'Pass';
            app.PassEditField = uieditfield(app.FTPPanel, 'text');
            app.PassEditField.Enable = 'off';
            app.PassEditField.Value = 'anonymous';
            app.RemoteDirectoryEditFieldLabel = uilabel(app.FTPPanel);
            app.RemoteDirectoryEditFieldLabel.Enable = 'off';
            app.RemoteDirectoryEditFieldLabel.Text = 'Remote Directory';
            app.RemoteDirectoryEditField = uieditfield(app.FTPPanel, 'text');
            app.RemoteDirectoryEditField.Enable = 'off';
            app.RemoteDirectoryEditField.Value = 'phod/pub/soopdata/XBT/done';
            app.EditCheckBox = uicheckbox(app.FTPPanel);
            app.EditCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditCheckBoxValueChanged, true);
            app.EditCheckBox.Text = 'Edit';
            app.SaveconfigurationButton = uibutton(app.CONFIGTab, 'push');
            app.SaveconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigurationButtonPushed, true);
            app.SaveconfigurationButton.Tooltip = {'Save current configuration'};
            app.SaveconfigurationButton.Text = 'Save configuration';
            app.BrowseButton = uibutton(app.CONFIGTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ScaleFactorEditFieldLabel = uilabel(app.CONFIGTab);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.CONFIGTab, 'numeric');
            app.ScaleFactorEditField.Limits = [-90 90];
            app.ScaleFactorEditField.ValueDisplayFormat = '%.2f';
            app.ScaleFactorEditField.Tooltip = {'Delta displacement for PAN or ZOOM in degrees'};
            app.ScaleFactorEditField.Value = 1;
            app.MapTypeDropDownLabel = uilabel(app.CONFIGTab);
            app.MapTypeDropDownLabel.Text = 'Map Type';
            app.MapTypeDropDown = uidropdown(app.CONFIGTab);
            app.MapTypeDropDown.Items = {'streets-light', 'streets-dark', 'streets', 'satellite', 'topographic', 'landcover', 'colorterrain', 'grayterrain', 'bluegreen', 'grayland', 'darkwater'};
            app.MapTypeDropDown.Tooltip = {'Select type of map to display before plotting'};
            app.MapTypeDropDown.Value = 'bluegreen';
            app.CurrentfilepathEditFieldLabel = uilabel(app.CONFIGTab);
            app.CurrentfilepathEditFieldLabel.Text = 'Current file path';
            app.CurrentfilepathEditField = uieditfield(app.CONFIGTab, 'text');
            app.CurrentfilepathEditField.Editable = 'off';
            app.CurrentfilepathEditField.Enable = 'off';
            app.DefaultconfigurationButton = uibutton(app.CONFIGTab, 'state');
            app.DefaultconfigurationButton.Enable = 'off';
            app.DefaultconfigurationButton.Tooltip = {'If there is a Configuration File it will be loaded when starting the App'};
            app.DefaultconfigurationButton.Text = 'Default configuration';
            app.Image = uiimage(app.CONFIGTab);
            app.Image.ImageSource = 'aomloriginalperfect.jpg';
            app.XBTPLOTTERv1012023Label = uilabel(app.CONFIGTab);
            app.XBTPLOTTERv1012023Label.Text = 'XBT PLOTTER v1 01-2023';
            app.TABLETab = uitab(app.TabGroup);
            app.TABLETab.Title = 'TABLE';
            app.UITable = uitable(app.TABLETab);
            app.UITable.ColumnName = {'Index'; 'CallSign'; 'Ship'; 'Line'; 'DateTime'; 'Latitude'; 'Longitude'; 'RemoteDir;File'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XBTPlotterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end